classdef Pool_transmit_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ChirpPathEditField            matlab.ui.control.EditField
        MOCZexperimentGUIMOCZsignalsmodeLabel  matlab.ui.control.Label
        TransmitChirpfortestCheckBox  matlab.ui.control.CheckBox
        GeneratenewMOCZsignalPanel    matlab.ui.container.Panel
        GeneratetransmitButton        matlab.ui.control.Button
        PausetimeEditField            matlab.ui.control.NumericEditField
        PausetimesEditFieldLabel      matlab.ui.control.Label
        recordingsPathEditField       matlab.ui.control.EditField
        ChooserecordingspathButton    matlab.ui.control.Button
        SignalfilesqueueLabel         matlab.ui.control.Label
        ProgressTextArea              matlab.ui.control.TextArea
        ProgressTextAreaLabel         matlab.ui.control.Label
        LoadedFilesTextArea           matlab.ui.control.TextArea
        Image                         matlab.ui.control.Image
        transmitphotoButton           matlab.ui.control.Button
        START_button                  matlab.ui.control.Button
        loadSignalFolder_button       matlab.ui.control.Button
        loadSignalFile_button         matlab.ui.control.Button
    end

    
    properties (Access = public)
        TransmitSignalsData; % full data on the tranmis signal
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadSignalFile_button
        function loadSignalFile_buttonButtonPushed(app, event)
            %Open a dialog to select only .mat files
            [fileName, filePath] = uigetfile('*.mat', 'Select a .MAT-file');
            figure(app.UIFigure);

            %Check if the user clicked "Cancel"
            if isequal(fileName, 0)
                return; % Exit the function without doing anything
            end
        
            % Display the file name in the text box next to the button
            % (Replace 'signal_file_name' with the actual name of your text component in the App Designer hierarchy)
            app.LoadedFilesTextArea.Value = fileName; 
        
            %Load the file and extract the data
            fullFilePath = fullfile(filePath, fileName);
            
            try
                loadedData = load(fullFilePath);
                safeFieldName = matlab.lang.makeValidName(erase(fileName, '.mat'));
                
                %Create a brand-new, empty structure
                freshStruct = struct();
                
                % Add your single loaded file to this fresh structure
                freshStruct.(safeFieldName) = loadedData;
                
                % OVERWRITE the app property (wiping old data)
                app.TransmitSignalsData = freshStruct;
                
                %OVERWRITE the text area so it only shows the new file
                app.LoadedFilesTextArea.Value = {fileName};
                
            catch 
                uialert(app.UIFigure, 'The selected file does not contain valid data.', 'Load Error');
            end
        end

        % Button pushed function: loadSignalFolder_button
        function loadSignalFolder_buttonButtonPushed(app, event)
            %Open a dialog to select a folder
            folderPath = uigetdir('', 'Select a Folder containing MAT-files');
            figure(app.UIFigure);
            
            % Check if the user clicked "Cancel"
            if isequal(folderPath, 0)
                return; 
            end
            
            %Get a list of all .mat files in that folder
            matFiles = dir(fullfile(folderPath, '*.mat'));

            % Initialize an empty cell array to hold the file names for display
            fileNamesForDisplay = {};
            
            % Check if the folder is empty of .mat files
            if isempty(matFiles)
                uialert(app.UIFigure, 'No .mat files were found in this folder.', 'Empty Folder');
                return;
            end
            
            %Initialize a temporary struct to hold everything
            masterDataStruct = struct();
            
            % Loop through every file, load it, and save it to the struct
            for i = 1:length(matFiles)
                fileName = matFiles(i).name;
                fullFilePath = fullfile(folderPath, fileName);
                
                try
                    % Load all variables from this specific file (P, signal_pb_total, simParams)
                    loadedData = load(fullFilePath);
                    
                    % Create a safe field name (e.g., "my_data.mat" becomes "my_data")
                    safeFieldName = matlab.lang.makeValidName(erase(fileName, '.mat'));
                    
                    % Save it as a nested structure inside the master struct
                    masterDataStruct.(safeFieldName) = loadedData;

                    %Add the original file name to our display list as a new row
                    fileNamesForDisplay{end+1, 1} = fileName;
                    
                catch
                    % If one file is corrupted, you can warn the user but keep loading the rest
                    warning('Could not load file: %s', fileName);
                end
            end
            
            % 5. Save the massive master structure to your app's property
            app.TransmitSignalsData = masterDataStruct;
            
            % Optional: Update a text box next to the button to show success
            % app.folder_status_text.Value = sprintf('Successfully loaded %d files.', length(matFiles));

            app.LoadedFilesTextArea.Value = fileNamesForDisplay;


        end

        % Button pushed function: START_button
        function START_buttonButtonPushed(app, event)
            
            % =========================================================
            % Check what the user actually wants to do
            % =========================================================
            doChirp = app.TransmitChirpfortestCheckBox.Value;
            hasQueue = ~isempty(app.TransmitSignalsData);
            
            % If they didn't check the box AND the queue is empty, THEN throw the error
            if ~doChirp && ~hasQueue
                uialert(app.UIFigure, 'No data loaded and Chirp is unchecked. Nothing to transmit!', 'Error');
                return;
            end

            % =========================================================
            % Verify the save directory
            % =========================================================
            saveDirectory = app.recordingsPathEditField.Value;
            
            % Check if the string is empty OR if the folder somehow got deleted
            if isempty(saveDirectory) || ~isfolder(saveDirectory)
                uialert(app.UIFigure, 'Please select a valid Save Directory before transmitting.', 'Save Error');
                return;
            end
                        
            % =========================================================
            % INITIALIZE HARDWARE (ONCE before the loop)
            % =========================================================
            try
                d = daq("ni");
                devList = daqlist;
                devID = devList.DeviceID(1);
                
                % Set up the physical channels (match these to your actual wiring!)
                addinput(d, devID, "ai0", "Voltage");
                addinput(d, devID, "ai1", "Voltage");
                addoutput(d, devID, "ao0", "Voltage");
            catch
                uialert(app.UIFigure, 'Could not connect to NI Sampler. Check USB/Power.', 'Hardware Error');
                return; % Stop execution if hardware fails
            end
            
            % Set up a text file to log the session
            logFileName = fullfile(saveDirectory, 'transmission_session.txt'); 
            fid = fopen(logFileName, 'a');
            fprintf(fid, '--- Starting new transmission batch at %s ---\n', char(datetime('now')));

            % =========================================================
            % TRANSMIT CHIRP (If requested)
            % =========================================================
            if doChirp
                chirpPath = app.ChirpPathEditField.Value;
                
                % Verify the path exists
                if isempty(chirpPath) || ~isfile(chirpPath)
                    uialert(app.UIFigure, 'Chirp file path is invalid.', 'Error');
                    fclose(fid);
                    return;
                end
                
                try
                    % Load ONLY the 'y_scaled' variable
                    loadedChirp = load(chirpPath, 'y_scaled');
                    chirpSignal = loadedChirp.y_scaled;
                    
                    % Update UI to show the chirp is running
                    app.ProgressTextArea.Value = 'Transmitting: CHIRP TEST...';
                    drawnow;
                    
                    % Set sampling rate for the chirp (Update to match your chirp's fs!)
                    d.Rate = 200e3; 
                    
                    % Transmit the chirp
                    preload(d, chirpSignal);

                    % Transmit and record the voltages
                    chirpRecordedData = readwrite(d, chirpSignal);
                    fprintf(fid, 'Transmitted CHIRP TEST successfully.\n');

                    % SAVE ONLY THE RECORDED VOLTAGES
                    chirpSavePath = fullfile(saveDirectory, 'chirp_recorded_result.mat');
                    save(chirpSavePath, 'chirpRecordedData');
                    
                    % Pause using your GUI's Pause time Edit Field!
                    % (Replace 'PausetimesEditField' with your actual component name)
                    pause(app.PausetimeEditField.Value);
                    
                catch
                    uialert(app.UIFigure, 'Failed to load or transmit chirp. Ensure the file contains "y_scaled".', 'Chirp Error');
                    fclose(fid);
                    return;
                end
            end
            
            % =========================================================
            % BATCH EXECUTION LOOP
            % =========================================================
            if hasQueue
                queuedFiles = fieldnames(app.TransmitSignalsData);
                for i = 1:length(queuedFiles)
                    currentName = queuedFiles{i};
                    
                    % Extract the data for this specific iteration
                    currentSignal = app.TransmitSignalsData.(currentName).signal_pb_total;
                    currentParams = app.TransmitSignalsData.(currentName).simParams;
                    
                    % Update UI to show progress
                    app.ProgressTextArea.Value = sprintf('Transmitting: %s...', currentName);
                    drawnow; 
                    
                    % Extract sampling rate from saved struct
                    if isfield(currentParams, 'Fs')
                        d.Rate = currentParams.Fs;
                    else
                        warning('No "Fs" found in simParams for %s. Using default 200e3.', currentName);
                        d.Rate = 200e3; 
                    end
                    
                    % Preload the signal to the hardware buffer to prevent lag
                    preload(d, currentSignal);
                    
                    % Transmit and Record simultaneously
                    % inData will be a timetable containing the recorded ai0 and ai1 voltages
                    recordedData = readwrite(d, currentSignal);
                    
                    % ----------------------------------------------------
                    % Log the success
                    % ----------------------------------------------------
                    fprintf(fid, 'Transmitted %s successfully at fs=%d.\n', currentName, d.Rate);
                    % Grab the current list of files from the Text Area
                    currentList = app.LoadedFilesTextArea.Value;
                    
                    % Ensure it is a cell array so we can edit a specific row
                    if ischar(currentList)
                        currentList = {currentList};
                    end
                    
                    % Add a checkmark to the current file's line
                    currentList{i} = sprintf('✅ %s', currentList{i});
                    
                    % Push the updated list back to the GUI
                    app.LoadedFilesTextArea.Value = currentList;
                    drawnow; % Force the GUI to update immediately
    
                    
                    % ----------------------------------------------------
                    % SAVE THE RECORDED DATA AND PARAMETERS
                    % ----------------------------------------------------
                    % Create a new filename based on the original signal name
                    baseFileName = sprintf('%s_recorded_result.mat', currentName);
                    fullSavePath = fullfile(saveDirectory, baseFileName);
                    
                    % Save both the new recording AND the original struct
                    save(fullSavePath, 'recordedData', 'currentParams');
                    
                    % Pause for 2 seconds before the next transmission
                    if i < length(queuedFiles)
                        pause(app.PausetimeEditField.Value); %pause(2) default
                    end
                end
                
                fclose(fid);
                uialert(app.UIFigure, 'Batch transmission complete!', 'Success');
            end

        end

        % Button pushed function: ChooserecordingspathButton
        function ChooserecordingspathButtonPushed(app, event)
            % Open a dialog to select a folder
            saveDir = uigetdir('', 'Select Directory to Save Results of current batch');
            
            % Force the app window back to the front
            figure(app.UIFigure);
            
            % Check if the user clicked "Cancel"
            if isequal(saveDir, 0)
                return; 
            end
            
            % Display the path in the UI
            % (Replace 'SavePathEditField' with the actual name of your component)
            app.recordingsPathEditField.Value = saveDir;
        end

        % Value changed function: TransmitChirpfortestCheckBox
        function TransmitChirpfortestCheckBoxValueChanged(app, event)
            value = app.TransmitChirpfortestCheckBox.Value;
            if value == true
                app.MOCZexperimentGUIMOCZsignalsmodeLabel.Text = "MOCZ experiment GUI - Chirp mode";
                app.MOCZexperimentGUIMOCZsignalsmodeLabel.BackgroundColor = '#FFB6C1';
                app.loadSignalFile_button.Enable = "off";
                app.loadSignalFolder_button.Enable = "off";

                app.TransmitSignalsData = []; 
                app.LoadedFilesTextArea.Value = '';
                
                %Open dialog to select the chirp file
                [fileName, filePath] = uigetfile('*.mat', 'Select Chirp .MAT file');
                
                % Force the app window back to the front
                figure(app.UIFigure); 
                
                % Check if the user clicked "Cancel"
                if isequal(fileName, 0)
                    % Uncheck the box since they canceled
                    app.TransmitChirpfortestCheckBox.Value = false;
                    return; 
                end
                
                %  Save the full path to the edit field below the checkbox
                % (Replace 'ChirpPathEditField' with your actual UI component name)
                app.ChirpPathEditField.Value = fullfile(filePath, fileName);
            else
                % If they uncheck the box, clear out the text field
                app.loadSignalFile_button.Enable = "on";
                app.loadSignalFolder_button.Enable = "on";
                app.ChirpPathEditField.Value = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadSignalFile_button = uibutton(app.UIFigure, 'push');
            app.loadSignalFile_button.ButtonPushedFcn = createCallbackFcn(app, @loadSignalFile_buttonButtonPushed, true);
            app.loadSignalFile_button.Text = 'load file.mat';
            app.loadSignalFolder_button = uibutton(app.UIFigure, 'push');
            app.loadSignalFolder_button.ButtonPushedFcn = createCallbackFcn(app, @loadSignalFolder_buttonButtonPushed, true);
            app.loadSignalFolder_button.Text = 'load folder';
            app.START_button = uibutton(app.UIFigure, 'push');
            app.START_button.ButtonPushedFcn = createCallbackFcn(app, @START_buttonButtonPushed, true);
            app.START_button.Text = {'START'; 'TRANSMIT'};
            app.transmitphotoButton = uibutton(app.UIFigure, 'push');
            app.transmitphotoButton.Text = 'transmit photo';
            app.Image = uiimage(app.UIFigure);
            app.LoadedFilesTextArea = uitextarea(app.UIFigure);
            app.ProgressTextAreaLabel = uilabel(app.UIFigure);
            app.ProgressTextAreaLabel.Text = 'Progress';
            app.ProgressTextArea = uitextarea(app.UIFigure);
            app.SignalfilesqueueLabel = uilabel(app.UIFigure);
            app.SignalfilesqueueLabel.Text = 'Signal files queue';
            app.ChooserecordingspathButton = uibutton(app.UIFigure, 'push');
            app.ChooserecordingspathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooserecordingspathButtonPushed, true);
            app.ChooserecordingspathButton.Text = {'Choose recordings'; ' path'};
            app.recordingsPathEditField = uieditfield(app.UIFigure, 'text');
            app.recordingsPathEditField.Editable = 'off';
            app.PausetimesEditFieldLabel = uilabel(app.UIFigure);
            app.PausetimesEditFieldLabel.Text = 'Pause time [s]';
            app.PausetimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PausetimeEditField.Value = 2;
            app.GeneratenewMOCZsignalPanel = uipanel(app.UIFigure);
            app.GeneratenewMOCZsignalPanel.Title = 'Generate new MOCZ signal';
            app.GeneratetransmitButton = uibutton(app.GeneratenewMOCZsignalPanel, 'push');
            app.GeneratetransmitButton.Text = 'Generate & transmit';
            app.TransmitChirpfortestCheckBox = uicheckbox(app.UIFigure);
            app.TransmitChirpfortestCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransmitChirpfortestCheckBoxValueChanged, true);
            app.TransmitChirpfortestCheckBox.Text = 'Transmit Chirp for test';
            app.MOCZexperimentGUIMOCZsignalsmodeLabel = uilabel(app.UIFigure);
            app.MOCZexperimentGUIMOCZsignalsmodeLabel.Text = 'MOCZ experiment GUI - MOCZ signals mode';
            app.ChirpPathEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pool_transmit_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end