classdef recieveGui_27_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SNRTextArea                    matlab.ui.control.TextArea
        SNRTextAreaLabel               matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        errrateTextArea                matlab.ui.control.TextArea
        errrateTextAreaLabel           matlab.ui.control.Label
        chirpTextArea                  matlab.ui.control.TextArea
        chirpTextAreaLabel             matlab.ui.control.Label
        KTextArea                      matlab.ui.control.TextArea
        KTextAreaLabel                 matlab.ui.control.Label
        LTextArea                      matlab.ui.control.TextArea
        LTextAreaLabel                 matlab.ui.control.Label
        messagedataLabel               matlab.ui.control.Label
        decodedandencodedsymbolsLabel  matlab.ui.control.Label
        spectrogramaLabel              matlab.ui.control.Label
        nameOfFile                     matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        matlab2026Label                matlab.ui.control.Label
        loadrecievedfilematButton      matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        recievedSignalFileName = [];
        timeAndDataMatrix = [];
        
        L = [];
        K = [];
        isChirp = [];
        snr = [];
        P = [];
        B = [];
        simParams = [];
    end
    
    methods (Access = private)
        
        function extractFromStruct(app, recievedSignalStruct)
            %notating:
            ParamsStrct = recievedSignalStruct.currentParams;
            signalData = recievedSignalStruct.recordedData;

            %updating values of params (error ratio will be after.):
            app.LTextArea.Value = ParamsStrct.L;
            app.L = ParamsStrct.L;
            app.KTextArea.Value = ParamsStrct.K;
            app.K = ParamsStrct.K;
            app.simParams = ParamsStrct;
            % TODO: handle these 3:
            % 1. app.chirpTextArea.Value = ParamsStrct.isChirp; not exist?
            % app.isChirp = ParamsStrct.isChirp;
            % 2. app.SNRTextArea.Value = ParamsStrct.snr;
            % app.snr = ParamsStrct.snr;
            % 3. app.P = ParamsStrct.P;
            
            % updating the data (1st coll in matrix is time and every col
            % after is data in channel: Dev5_ai0, Dev5_ai1).
            % this is why we must track what channel we used! right now we
            % only treat the second col as the good one.
            % TODO: a jumping prompt that stated that we assume our data is "signalData.VariableNames(1)" port! 
            app.timeAndDataMatrix(:, 1) = seconds(signalData.Time);
            app.timeAndDataMatrix = [app.timeAndDataMatrix, signalData.Variables];

            % TODO: dizet decoder (+ cross correlator?) that will calculate: 
            % [~, ratio] = biterr(P, DiZeT(simParams, strct.signal)); app.errrateTextArea.Value = ratio;
        end

        function results = analyzeSignal(app)
            %% 1. notating
            time = app.timeAndDataMatrix(:, 1);
            dataRecieved = app.timeAndDataMatrix(:, 2);
            simParams = app.simParams;
            Tsym = simParams.Tsym;
            Fs = simParams.Fs;
            sps = Tsym * Fs;
            K = app.K;
            L = app.K;
            B = app.B;
            P = app.P;

            %% 2. decoding process
            % to baseband:
            mixed_x = dataRecieved .* exp(-1i*2*pi*fc*time);
            % need this? % Apply matched filter at High Rate (Fs). x_bb_filtered = filter(h_coeff, 1, mixed_x);
            x_bb_filtered = mixed_x; %till we decide the line before..
            
            % Exact Zadoff-Chu Synchronization
            N = simParams.zadoffChuPair(2);
            u = simParams.zadoffChuPair(1);
            zc_ref = zadoffChuSeq(u, N);

            % Upsample reference to match Fs rate
            zc_upsamp = upsample(zc_ref, sps);

            % Cross-correlate filtered signal with the upsampled reference
            [xc, lags] = xcorr(x_bb_filtered, zc_upsamp);
            abs_xc = abs(xc);
            [maxVal, maxIdx] = max(abs_xc);
            peakLag = lags(maxIdx);

            % -- Peak Validation (PAPR check) --
            % If the peak isn't significantly higher than the average correlation,
            % you are likely just looking at noise.
            if (maxVal / mean(abs_xc)) < 15 
                warning('Low correlation peak detected. Packet may be missing or heavily corrupted.');
            end

            % Downsampling
            % The correlation peak implicitly accounts for the filter's group delay
            % peakLag tells us exactly where the first sample of the ZC sequence is.
            sync_start_sample_Fs = peakLag + 1;

            % To get to the first data symbol, we skip the ZC sequence (N) and the Guard (L)
            data_start_sample_Fs = sync_start_sample_Fs + ((N + L) * sps);

            % We need B messages, each of length (K + 1 + L)
            total_data_symbols = B * (K + 1 + L);
            end_sample_Fs = data_start_sample_Fs + (total_data_symbols - 1) * sps;

            % Safety check: Did the recording cut off before the packet ended?
            if end_sample_Fs > length(x_bb_filtered)
                error('Recorded signal is too short to contain the full B messages.');
            end

            % Downsample starting EXACTLY at the optimal phase
            sampled_data = x_bb_filtered(data_start_sample_Fs : sps : end_sample_Fs);
            x_decoded = sqrt(2) * sampled_data;

            % now using dizet decoder:
            x_decoded_matrix = reshape(x_decoded, K + 1 + L, B);
            R = simParams.R;
            theta_c = simParams.theta_c;

            for b = 1 : B
                x_vec_decoded = x_decoded_matrix(:, b);
                
                % Run decoder on the current message block
                message_rec = DiZeT(R, theta_c, x_vec_decoded, K);
                P_rec_D(:, b) = message_rec;
            end
            %% 4. update biterr text box and ploting in axes objects
            % todo: what i wrote in chapter title.

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadrecievedfilematButton
        function loadrecievedfilematButtonPushed(app, event)
            %Open a dialog to select only .mat files
            [fileName, filePath] = uigetfile('*.mat', 'Select a .MAT-file');

            %Check if the user clicked "Cancel"
            if isequal(fileName, 0)
                return; % Exit the function without doing anything
            end

            % Display the file name in the text box next to the button
            % (Replace 'signal_file_name' with the actual name of your text component in the App Designer hierarchy)
            app.nameOfFile.Value = fileName;

            %update property:
            app.recievedSignalFileName = fileName;

            %Load the file and extract the data
            fullFilePath = fullfile(filePath, fileName);

            try
                % Load ONLY the 'signal' variable from the .mat file into a structure
                loadedData = load(fullFilePath);

                fprintf('loaded succesfuly! now try to display...');
            catch 
                % Pop up an error if the .mat file doesn't actually contain a variable named 'signal'
                uialert(app.UIFigure, 'The selected file does not contain a recievedSignal struct.', 'Load Error');
            end

            try
                % update params text boxes in gui, and update properties
                extractFromStruct(app, loadedData);

                % analyze the signal we got:
                %%%%%%% method analyzeSignal

            catch
                % TODO: complete
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.loadrecievedfilematButton = uibutton(app.UIFigure, 'push');
            app.loadrecievedfilematButton.ButtonPushedFcn = createCallbackFcn(app, @loadrecievedfilematButtonPushed, true);
            app.loadrecievedfilematButton.Text = 'load recieved file.mat';
            app.matlab2026Label = uilabel(app.UIFigure);
            app.matlab2026Label.Text = 'matlab 2026';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.nameOfFile = uitextarea(app.UIFigure);
            app.spectrogramaLabel = uilabel(app.UIFigure);
            app.spectrogramaLabel.Text = 'spectrograma';
            app.decodedandencodedsymbolsLabel = uilabel(app.UIFigure);
            app.decodedandencodedsymbolsLabel.Text = 'decoded and encoded symbols';
            app.messagedataLabel = uilabel(app.UIFigure);
            app.messagedataLabel.Text = 'message data:';
            app.LTextAreaLabel = uilabel(app.UIFigure);
            app.LTextAreaLabel.Text = 'L';
            app.LTextArea = uitextarea(app.UIFigure);
            app.KTextAreaLabel = uilabel(app.UIFigure);
            app.KTextAreaLabel.Text = 'K';
            app.KTextArea = uitextarea(app.UIFigure);
            app.chirpTextAreaLabel = uilabel(app.UIFigure);
            app.chirpTextAreaLabel.Text = 'chirp?';
            app.chirpTextArea = uitextarea(app.UIFigure);
            app.errrateTextAreaLabel = uilabel(app.UIFigure);
            app.errrateTextAreaLabel.Text = 'err rate';
            app.errrateTextArea = uitextarea(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'mat file with struct recievedSignal';
            app.SNRTextAreaLabel = uilabel(app.UIFigure);
            app.SNRTextAreaLabel.Text = 'SNR';
            app.SNRTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recieveGui_27_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end