classdef recieveGui_28_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MessageDataPanel              matlab.ui.container.Panel
        Label_Bname                   matlab.ui.control.Label
        Label_Bval                    matlab.ui.control.Label
        Label_portval                 matlab.ui.control.Label
        Label_portname                matlab.ui.control.Label
        Label_errRatename             matlab.ui.control.Label
        Label_errRateval              matlab.ui.control.Label
        Label_Chirpname               matlab.ui.control.Label
        Label_Chirpval                matlab.ui.control.Label
        Label_SNRname                 matlab.ui.control.Label
        Label_SNRval                  matlab.ui.control.Label
        Label_Kname                   matlab.ui.control.Label
        Label_Kval                    matlab.ui.control.Label
        Label_Lname                   matlab.ui.control.Label
        Label_Lval                    matlab.ui.control.Label
        RECIEVINGGUILabel             matlab.ui.control.Label
        SymbolsandImageVisualizationPanel  matlab.ui.container.Panel
        imageAxes                     matlab.ui.control.UIAxes
        circleAxes                    matlab.ui.control.UIAxes
        SignalDataVisualizationPanel  matlab.ui.container.Panel
        freqAxes                      matlab.ui.control.UIAxes
        signalAxes                    matlab.ui.control.UIAxes
        Label_2                       matlab.ui.control.Label
        nameOfFile                    matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        matlab2023bLabel              matlab.ui.control.Label
        loadrecievedfilematButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        recievedSignalFileName = [];
        timeAndDataMatrix = [];
        
        L = [];
        K = [];
        isChirp = [];
        snr = [];
        P = [];
        B = [];
        simParams = [];
    end
    
    methods (Access = private)
        
        function struct2vars(app)
            S = app.simParams;
            fields = fieldnames(S);
            for i = 1:numel(fields)
                fieldName = fields{i};
                fieldValue = S.(fieldName);
                assignin('caller', fieldName, fieldValue);
            end
        end
        
        function extractFromStruct(app, recievedSignalStruct)
            %notating:
            ParamsStrct = recievedSignalStruct.currentParams;
            signalData = recievedSignalStruct.recordedData;

            %updating values of params (error ratio will be after.):
            app.Label_Lval.Text = string(ParamsStrct.L);
            app.L = ParamsStrct.L;

            app.Label_Kval.Text = string(ParamsStrct.K);
            app.K = ParamsStrct.K;

            app.Label_Bval.Text = string(ParamsStrct.B);
            app.B = ParamsStrct.B;

            app.simParams = ParamsStrct;

            colName = signalData.Properties.VariableNames{1};
            app.Label_portval.Text = colName;
            % TODO: handle these 3:
            % 1. app.chirpTextArea.Value = ParamsStrct.isChirp; not exist?
            % app.isChirp = ParamsStrct.isChirp;
            % 2. app.SNRTextArea.Value = ParamsStrct.snr;
            % app.snr = ParamsStrct.snr;
            % 3. app.P = ParamsStrct.P;
            
            % updating the data (1st coll in matrix is time and every col
            % after is data in channel: Dev5_ai0, Dev5_ai1).
            % this is why we must track what channel we used! right now we
            % only treat the second col as the good one.
            % TODO: a jumping prompt that stated that we assume our data is "signalData.VariableNames(1)" port! 
            app.timeAndDataMatrix(:, 1) = seconds(signalData.Time);
            app.timeAndDataMatrix = [app.timeAndDataMatrix, signalData.Variables];

            % TODO: dizet decoder (+ cross correlator?) that will calculate: 
            % [~, ratio] = biterr(P, DiZeT(simParams, strct.signal)); app.errrateTextArea.Value = ratio;
        end

        function analyzeSignal(app)
            %% 1. notating - assumed data is 2nd col only!! in timetable
            time = app.timeAndDataMatrix(:, 1);
            dataRecieved = app.timeAndDataMatrix(:, 2);
            struct2vars(app);
            % simParams = app.simParams;
            sps = Tsym * Fs;

            %% 2. decoding process
            % to baseband:
            % Matched Filtering (Baseband Conversion at Fs)
            span = 6;
            h_coeff = rcosdesign(betha, span, sps);
            % Create time vector based on the actual length of the recording
            t = (0:length(dataRecieved)-1)' / Fs;
            
            % Downconvert to baseband
            mixed_x = dataRecieved .* exp(-1i*2*pi*fc*t); % not the time from timetable!
            
            % Apply raised cosine matched filter at High Rate (Fs)
            x_bb_filtered = filter(h_coeff, 1, mixed_x);
            
            % Exact Zadoff-Chu Synchronization
            N = zadoffChuPair(2);
            u = zadoffChuPair(1);
            zc_ref = zadoffChuSeq(u, N);

            % Upsample reference to match Fs rate
            zc_upsamp = upsample(zc_ref, sps);

            % Cross-correlate filtered signal with the upsampled reference
            [xc, lags] = xcorr(x_bb_filtered, zc_upsamp);
            abs_xc = abs(xc);
            [maxVal, maxIdx] = max(abs_xc);
            peakLag = lags(maxIdx);

            % -- Peak Validation (PAPR check) --
            % If the peak isn't significantly higher than the average correlation,
            % you are likely just looking at noise.
            if (maxVal / mean(abs_xc)) < 15 
                warning('Low correlation peak detected. Packet may be missing or heavily corrupted.');
            end

            % Downsampling
            % The correlation peak implicitly accounts for the filter's group delay
            % peakLag tells us exactly where the first sample of the ZC sequence is.
            sync_start_sample_Fs = peakLag + 1;

            % To get to the first data symbol, we skip the ZC sequence (N) and the Guard (L)
            data_start_sample_Fs = sync_start_sample_Fs + ((N + L) * sps);

            % We need B messages, each of length (K + 1 + L)
            total_data_symbols = B * (K + 1 + L);
            end_sample_Fs = data_start_sample_Fs + (total_data_symbols - 1) * sps;

            % Safety check: Did the recording cut off before the packet ended?
            if end_sample_Fs > length(x_bb_filtered)
                error('Recorded signal is too short to contain the full B messages.');
            end

            % Downsample starting EXACTLY at the optimal phase
            sampled_data = x_bb_filtered(data_start_sample_Fs : sps : end_sample_Fs);
            x_decoded = sqrt(2) * sampled_data;

            % now using dizet decoder:
            x_decoded_matrix = reshape(x_decoded, K + 1 + L, B);

            for b = 1 : B
                x_vec_decoded = x_decoded_matrix(:, b);
                % Run decoder on the current message block
                message_rec = DiZeT(R, theta_c, x_vec_decoded, K);
                P_rec_D(:, b) = message_rec;
            end
            %% 3. update biterr text box and ploting in axes objects (take random b from B)
            % update biterr & message num texts
            [~, ratio] = biterr(P, P_rec_D);
            app.Label_errRateval.Text = string(ratio);

            % 1. circle axes (random packet)
            idx = randi(B);
            x_vec_decoded = x_decoded_matrix(:, idx);
            decoded_alphas = roots(flip(x_vec_decoded'));
            M = P(:, idx);
            alphas = ((1-M)*(R^(-1)) + M * R) .* exp(1i*theta_c);

            hold(app.circleAxes, 'on');
            h1 = scatter(app.circleAxes, real(decoded_alphas), imag(decoded_alphas), 50, 'filled', 'b');
            grid(app.circleAxes, 'on'); 
            axis(app.circleAxes, 'equal');
            h2 = scatter(app.circleAxes, real(alphas), imag(alphas), 50, 'filled', 'g');
            
            thetas_for_plot = linspace(0, 2*pi, 300);
            plot(app.circleAxes, R * exp(1i*thetas_for_plot), 'r', 'LineWidth', 1);
            plot(app.circleAxes, (1/R) * exp(1i*thetas_for_plot), 'r', 'LineWidth', 1);
            plot(app.circleAxes, exp(1i*thetas_for_plot), 'k--', 'LineWidth', 0.5);
            
            legend(app.circleAxes, [h1, h2], {'Signal Symbols', 'Original Symbols'});
            title(app.circleAxes, 'Enc. and Rec. Symbols of Random Packet'); %Huffman BMOCZ
            xlabel(app.circleAxes, 'Re(z)');
            ylabel(app.circleAxes, 'Im(z)');
            hold(app.circleAxes, 'off');
            % app.Label_messagenumberVal.Text = string(idx) + " / " + string(B); % 1/20, 7/20, .. in label object

            % 2. image axes (if image..)
            % TODO

            % 3. signal axes
            plot(app.signalAxes, t, dataRecieved);
            grid(app.signalAxes, 'on');

            % freq axes
            N = size(t, 1);
            FT = fftshift(fft(dataRecieved));
            f = ((-N/2 : N/2-1) * (Fs/N)) / 1000;
            plot(app.freqAxes, f, abs(FT), 'LineWidth', 1.5);
            xlim(app.freqAxes, [-50, 50]);
            xline(app.freqAxes, -15, '--');
            xline(app.freqAxes, 15, '--');
            grid(app.freqAxes, 'on');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadrecievedfilematButton
        function loadrecievedfilematButtonPushed(app, event)
            %Open a dialog to select only .mat files
            [fileName, filePath] = uigetfile('*.mat', 'Select a .MAT-file');

            %Check if the user clicked "Cancel"
            if isequal(fileName, 0)
                return; % Exit the function without doing anything
            end

            % Display the file name in the text box next to the button
            % (Replace 'signal_file_name' with the actual name of your text component in the App Designer hierarchy)
            app.nameOfFile.Value = fileName;

            %update property:
            app.recievedSignalFileName = fileName;

            %Load the file and extract the data
            fullFilePath = fullfile(filePath, fileName);

            try
                % Load ONLY the 'signal' variable from the .mat file into a structure
                loadedData = load(fullFilePath);

                % fprintf('loaded succesfuly! now try to display...\n');
            catch 
                % Pop up an error if the .mat file doesn't actually contain a variable named 'signal'
                uialert(app.UIFigure, 'The selected file does not contain a recievedSignal struct.', 'Load Error');
            end

            % try
                % update params text boxes in gui, and update properties
                extractFromStruct(app, loadedData);

                % analyze the signal we got:
                %%%%%%% method analyzeSignal
                analyzeSignal(app);

            % catch
                % TODO: complete
            % end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadrecievedfilematButton = uibutton(app.UIFigure, 'push');
            app.loadrecievedfilematButton.ButtonPushedFcn = createCallbackFcn(app, @loadrecievedfilematButtonPushed, true);
            app.loadrecievedfilematButton.Text = 'load recieved file.mat';
            app.matlab2023bLabel = uilabel(app.UIFigure);
            app.matlab2023bLabel.Text = 'matlab 2023b';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.nameOfFile = uitextarea(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'mat file with struct recievedSignal';
            app.SignalDataVisualizationPanel = uipanel(app.UIFigure);
            app.SignalDataVisualizationPanel.Title = 'Signal Data Visualization';
            app.signalAxes = uiaxes(app.SignalDataVisualizationPanel);
            title(app.signalAxes, 'Message PB Signal')
            xlabel(app.signalAxes, 'Time [sec]')
            ylabel(app.signalAxes, 'Mgnitude')
            zlabel(app.signalAxes, 'Z')
            app.freqAxes = uiaxes(app.SignalDataVisualizationPanel);
            title(app.freqAxes, 'PB Signal Fourier Trans.')
            xlabel(app.freqAxes, 'Frequency (kHz)')
            ylabel(app.freqAxes, 'Magnitude')
            zlabel(app.freqAxes, 'Z')
            app.SymbolsandImageVisualizationPanel = uipanel(app.UIFigure);
            app.SymbolsandImageVisualizationPanel.Title = 'Symbols and Image Visualization';
            app.circleAxes = uiaxes(app.SymbolsandImageVisualizationPanel);
            title(app.circleAxes, 'Random Packet Symbols')
            xlabel(app.circleAxes, 'X')
            ylabel(app.circleAxes, 'Y')
            zlabel(app.circleAxes, 'Z')
            app.imageAxes = uiaxes(app.SymbolsandImageVisualizationPanel);
            title(app.imageAxes, 'image')
            xlabel(app.imageAxes, 'X')
            ylabel(app.imageAxes, 'Y')
            zlabel(app.imageAxes, 'Z')
            app.RECIEVINGGUILabel = uilabel(app.UIFigure);
            app.RECIEVINGGUILabel.Text = 'RECIEVING GUI';
            app.MessageDataPanel = uipanel(app.UIFigure);
            app.MessageDataPanel.Title = 'Message Data';
            app.Label_Lval = uilabel(app.MessageDataPanel);
            app.Label_Lval.Text = '_';
            app.Label_Lname = uilabel(app.MessageDataPanel);
            app.Label_Lname.Text = 'L:';
            app.Label_Kval = uilabel(app.MessageDataPanel);
            app.Label_Kval.Text = '_';
            app.Label_Kname = uilabel(app.MessageDataPanel);
            app.Label_Kname.Text = 'K:';
            app.Label_SNRval = uilabel(app.MessageDataPanel);
            app.Label_SNRval.Text = '_';
            app.Label_SNRname = uilabel(app.MessageDataPanel);
            app.Label_SNRname.Text = 'SNR:';
            app.Label_Chirpval = uilabel(app.MessageDataPanel);
            app.Label_Chirpval.Text = '_';
            app.Label_Chirpname = uilabel(app.MessageDataPanel);
            app.Label_Chirpname.Text = 'chirp?';
            app.Label_errRateval = uilabel(app.MessageDataPanel);
            app.Label_errRateval.Text = '_';
            app.Label_errRatename = uilabel(app.MessageDataPanel);
            app.Label_errRatename.Text = 'errRate:';
            app.Label_portname = uilabel(app.MessageDataPanel);
            app.Label_portname.Text = 'port name:';
            app.Label_portval = uilabel(app.MessageDataPanel);
            app.Label_portval.Text = '_';
            app.Label_Bval = uilabel(app.MessageDataPanel);
            app.Label_Bval.Text = '_';
            app.Label_Bname = uilabel(app.MessageDataPanel);
            app.Label_Bname.Text = 'B:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recieveGui_28_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end